classdef ruby_er_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        HANDPSDropDown       matlab.ui.control.DropDown
        HANDPSDropDownLabel  matlab.ui.control.Label
        HANDOCDropDown       matlab.ui.control.DropDown
        HANDOCDropDownLabel  matlab.ui.control.Label
        LOADButton           matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        Panel                matlab.ui.container.Panel
        UITable              matlab.ui.control.Table
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        x % Description
        y
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            

            eventname = event.EventName;
            switch eventname
                case 'WindowMouseMotion'
                    % Obtain Coordinates of mouse for each of the 4 axes

                    xlim(app.UIAxes,[1 10])
                    ylim(app.UIAxes,[1 10])
                    currentPoint1 = app.UIAxes.CurrentPoint(1,1:3);
                    x1 = currentPoint1(1);
                    y1 = currentPoint1(2);
                    app.x = currentPoint1(1);
                    app.y = currentPoint1(2);

                    % Change pointer to crosshair when within axes.
                    if (app.UIAxes.XLim(1)<x1)&&(x1<app.UIAxes.XLim(2)) && (app.UIAxes.YLim(1)<y1)&&(y1<app.UIAxes.YLim(2))
                        set(app.UIFigure,'Pointer','crosshair');
                        cla(app.UIAxes)
                        xline(app.UIAxes,x1,'-','Color','k')
                        title(app.UIAxes,[num2str(app.x) ' ' num2str(app.y)])
                    else
                        set(app.UIFigure,'Pointer','arrow'); % When outside of axes, return pointer to arrow
                    end


                case 'WindowMousePress'
                    % When mouse is pressed, get coordinates

            end


        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            

            useKEY = event.key;
            switch useKEY
                case 'a'
                    % apply numbers to table

                case 's'
                    % save numbers and advance table


            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {544, 544};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {95, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LOADButton = uibutton(app.LeftPanel, 'push');
            app.LOADButton.Text = 'LOAD';
            app.HANDOCDropDownLabel = uilabel(app.LeftPanel);
            app.HANDOCDropDownLabel.Text = 'HAND OC';
            app.HANDOCDropDown = uidropdown(app.LeftPanel);
            app.HANDPSDropDownLabel = uilabel(app.LeftPanel);
            app.HANDPSDropDownLabel.Text = 'HAND PS';
            app.HANDPSDropDown = uidropdown(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.RightPanel);
            app.Panel.Title = 'Panel';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ruby_er_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end