classdef SpikeQualityRView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DirectoryMenu           matlab.ui.container.Menu
        TimelinePanel           matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        EpcohPanel              matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        NUMLabel                matlab.ui.control.Label
        EPOCHLabel              matlab.ui.control.Label
        NEXTButton              matlab.ui.control.Button
        BACKButton              matlab.ui.control.Button
        UIAxes3_2               matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        TabGroup2               matlab.ui.container.TabGroup
        UMAPTab                 matlab.ui.container.Tab
        SHAPTab                 matlab.ui.container.Tab
        GridLayout6             matlab.ui.container.GridLayout
        UIAxes                  matlab.ui.control.UIAxes
        TabGroup                matlab.ui.container.TabGroup
        ClustersTab             matlab.ui.container.Tab
        OverlayMeanClustersTab  matlab.ui.container.Tab
        GridLayout5             matlab.ui.container.GridLayout
        UIAxes2                 matlab.ui.control.UIAxes
        FileOptionsPanel        matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        clusterFilesDD          matlab.ui.control.DropDown
        WaveMapButton           matlab.ui.control.Button
        Button2                 matlab.ui.control.Button
        LOADButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        spikeCaseLoc % Description
        spikeClustLoc
        spikeProcLoc
        ClusterList
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: DirectoryMenu
        function DirectoryMenuSelected(app, event)
            curDir = pwd;
            tmpRAWdata = uigetdir(curDir,'CHOOSE DATA DIRECTORY');
            app.spikeCaseLoc = tmpRAWdata;
            app.spikeProcLoc = [app.spikeCaseLoc , filesep , 'Processed Electrophysiology', filesep];
            app.spikeClustLoc = [app.spikeProcLoc , filesep , 'ClusteredSpikeTimes', filesep];

            % CD to Clustered folder
            cd(app.spikeClustLoc)

            % Get list
            allLIST1 = dir('*.mat');
            allLIST2 = {allLIST1.name};
            app.ClusterList = allLIST2;
            app.clusterFilesDD.Items = app.ClusterList;
            app.clusterFilesDD.Value = app.ClusterList{1};

            % app.depFile2load = app.FILESList.Items{1};
            % Get case name
            % firstPart = extractAfter(app.depFile2load,10);
            % app.DepthID = extractBefore(firstPart,'F');
            % 
            % app.FileNameLabel.Text = app.depFile2load;
            % app.DepthnameLabel.Text = app.DepthID;

            %%%% ENABLE specific button FUNCTIONS
            % app.LoadCase.Enable = 'on';

        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DirectoryMenu = uimenu(app.UIFigure);
            app.DirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @DirectoryMenuSelected, true);
            app.DirectoryMenu.Text = 'Directory';
            app.FileOptionsPanel = uipanel(app.UIFigure);
            app.FileOptionsPanel.Title = 'File Options';
            app.GridLayout = uigridlayout(app.FileOptionsPanel);
            app.LOADButton = uibutton(app.GridLayout, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.Button2 = uibutton(app.GridLayout, 'push');
            app.Button2.Text = 'Button2';
            app.WaveMapButton = uibutton(app.GridLayout, 'push');
            app.WaveMapButton.Text = 'WaveMap';
            app.clusterFilesDD = uidropdown(app.GridLayout);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.ClustersTab = uitab(app.TabGroup);
            app.ClustersTab.Title = 'Clusters';
            app.OverlayMeanClustersTab = uitab(app.TabGroup);
            app.OverlayMeanClustersTab.Title = 'Overlay Mean Clusters';
            app.GridLayout5 = uigridlayout(app.OverlayMeanClustersTab);
            app.UIAxes2 = uiaxes(app.GridLayout5);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.UMAPTab = uitab(app.TabGroup2);
            app.UMAPTab.Title = 'UMAP';
            app.SHAPTab = uitab(app.TabGroup2);
            app.SHAPTab.Title = 'SHAP';
            app.GridLayout6 = uigridlayout(app.SHAPTab);
            app.UIAxes = uiaxes(app.GridLayout6);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TimelinePanel = uipanel(app.UIFigure);
            app.TimelinePanel.Title = 'Timeline';
            app.GridLayout3 = uigridlayout(app.TimelinePanel);
            app.UIAxes3 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.EpcohPanel = uipanel(app.GridLayout3);
            app.EpcohPanel.Title = 'Epcoh';
            app.GridLayout4 = uigridlayout(app.EpcohPanel);
            app.BACKButton = uibutton(app.GridLayout4, 'push');
            app.BACKButton.Text = 'BACK';
            app.NEXTButton = uibutton(app.GridLayout4, 'push');
            app.NEXTButton.Text = 'NEXT';
            app.EPOCHLabel = uilabel(app.GridLayout4);
            app.EPOCHLabel.Text = 'EPOCH';
            app.NUMLabel = uilabel(app.GridLayout4);
            app.NUMLabel.Text = 'NUM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeQualityRView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end